classdef fMRIComplexityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        NonlinearAnalysisTab          matlab.ui.container.Tab
        EditField                     matlab.ui.control.EditField
        OpenButton                    matlab.ui.control.Button
        Tree                          matlab.ui.container.Tree
        UIAxes                        matlab.ui.control.UIAxes
        SingleTrialSampEnAnalysisButton  matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        SingleParticipantSampEnAnalysisButton  matlab.ui.control.Button
        SingleTimeSeriesDelayCalcButton  matlab.ui.control.Button
        AllParticipantsSampEnAnalysisButton  matlab.ui.control.Button
        UseROIMasksCheckBox           matlab.ui.control.CheckBox
        CombineTrialsCheckBox         matlab.ui.control.CheckBox
        ExistingSampEnMapsCheckBox    matlab.ui.control.CheckBox
        UIAxes2_2                     matlab.ui.control.UIAxes
        MaskAdjustmentTab             matlab.ui.container.Tab
        DAxialSagittalandCoronalViewsofCurrentlySelectedfMRIFolderPanel  matlab.ui.container.Panel
        TimeNPanel                    matlab.ui.container.Panel
        Slider                        matlab.ui.control.Slider
        UIAxes2                       matlab.ui.control.UIAxes
        AdjustmentPanel               matlab.ui.container.Panel
        PitchKnobLabel                matlab.ui.control.Label
        PitchKnob                     matlab.ui.control.Knob
        RollKnobLabel                 matlab.ui.control.Label
        RollKnob                      matlab.ui.control.Knob
        YawKnobLabel                  matlab.ui.control.Label
        YawKnob                       matlab.ui.control.Knob
        Image_4                       matlab.ui.control.Image
        XYOffsetLabel                 matlab.ui.control.Label
        Image_5                       matlab.ui.control.Image
        YZOffsetLabel                 matlab.ui.control.Label
        Image_6                       matlab.ui.control.Image
        XZOffsetLabel                 matlab.ui.control.Label
        ManagementPanel               matlab.ui.container.Panel
        EditField_2                   matlab.ui.control.EditField
        NextButton                    matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
        ApplytoThisButton             matlab.ui.control.Button
        ApplytoAllButton              matlab.ui.control.Button
        PreprocessingTab              matlab.ui.container.Tab
        PreprocessingStepButtonGroup  matlab.ui.container.ButtonGroup
        DICOMImportButton             matlab.ui.control.RadioButton
        RealignButton                 matlab.ui.control.RadioButton
        TimingandResliceButton        matlab.ui.control.RadioButton
        SpatialNormalizationButton    matlab.ui.control.RadioButton
        SpatialSmoothingButton        matlab.ui.control.RadioButton
        RunButton                     matlab.ui.control.Button
        TREditFieldLabel              matlab.ui.control.Label
        TREditField                   matlab.ui.control.NumericEditField
        VoxelSizeXEditFieldLabel      matlab.ui.control.Label
        VoxelSizeXEditField           matlab.ui.control.NumericEditField
        VoxelSizeYEditFieldLabel      matlab.ui.control.Label
        VoxelSizeYEditField           matlab.ui.control.NumericEditField
        VoxelSizeZEditFieldLabel      matlab.ui.control.Label
        VoxelSizeZEditField           matlab.ui.control.NumericEditField
        FWHMXEditFieldLabel           matlab.ui.control.Label
        FWHMXEditField                matlab.ui.control.NumericEditField
        FWHMYEditFieldLabel           matlab.ui.control.Label
        FWHMYEditField                matlab.ui.control.NumericEditField
        FWHMZEditFieldLabel           matlab.ui.control.Label
        FWHMZEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        %preprocessing tab
        ready
        file
        path %User selected primary directory
        files
        vol4D
        subdirectories %have it collect all HA and MCI folders, and SPMAnalysis folders within, as well each trial.
        mode %1 = dicom import, 2 = realign, 3 = timing and reslice, 4 = spatial normalization, 5 = spatial smoothing
        tr %tr value for timing
        numslices %number of slices, usually 63 (z-axis)
        ta %TA = (TR-(TR/num of slices))
        sliceorder %slice order, usually 1:1:numslices
        refslice %usually 1
        voxelx %voxelsize on x axis, usually 2.5
        voxely %voxelsize on y axis, usually 2.5
        voxelz %voxelsize on z axis, usually 3
        fwhmx %FWHM x for smoothing
        fwhmy %FWHM y for smoothing
        fwhmz %FWHM z for smoothing
        
        %nifti viewer properties
        timeN
        maskDir
        Limits
        SagittalSlice    %For selecting slice on sagittal plane
        CoronalSlice     %For selecting slice on coronal plane
        AxialSlice       %For selecting slice on axial plane
        curFolder         %current folder
        curVol           %current volume
        maskVol  %Use for alignment
        alignMask        %dummy mask where modifications are made
        maskInfo
        imgGet           %boolean for while loop
        alignImage       %This is the image
        rotMat           %rotation matrix, needs to be updated only when angles change.
        offSet           %offSet will be a 1x3 matrix for x, y, z
        rotationImg      %rotationImg will be a 1x3 matrix for pitch, roll, and yaw angles.
        
        %time series plot and SampEn analysis properties
        Intensity
        montageImg      %store montage image for dimensions
        mouseLoc        %location of mouse on montage
        mouseHold       %whether or not mouse button being held down.
        mouseState      %Which two slices to adjust based on mouse location when clicking.
        
        useROI
        comboTrials
        skipSampEn
        %X
        %Y
        %Z
        %d                %The directory chosen by user
        %files            %This is a structured list containing all files within a directory, specifically the NIfTI files
        %info
        %Volumes          %Stores 4D data - fMRI data with time as fourth dimension
        %Intensity        %Direct File Read Intensity, Time series
        N_Intensity      %Normalized Intensity
        N_Intensity_2    %Normalized Intensity for second set of data (180+ data points)
        
        R_Intensity      %Resting-Only Intensity
        T_Intensity      %Task-Only Intensity
        
        %TR               %The TR should be 2 here
        VolumetricSampEn %SampEn of whole time series
        VolumetricSampEnN
        VSampEn_Rest     %SampEn of resting time series
        VSampEn_Task     %SampEn of task time series
        
        CV_Whole         %3D Volume, Coefficient of Variation Map for whole time series
        CV_Rest          %3D Volume, Coefficient of Variation Map for resting time series
        CV_Task          %3D Volume, Coefficient of Variation Map for task time series
        
        CVW_Sum          %Mean calc for whole, resting, and task time series CV
        CVR_Sum
        CVT_Sum
        
        exclusionCount   %Not really useful, but we still need to report for some reason.
        R_EC
        T_EC
        
        Count            %How many voxels fall above threshold for inclusion in mean calc.
        %Sum              %Self explanatory, summation of SampEn values of every included voxel
        SD_Sum           %Standard Deviation summation, used for calculating std dev.
        
        R_Count          %Same as above, but for resting time series
        R_Sum
        R_SD_Sum
        
        T_Count          %Same as above, but for task time series
        T_Sum
        T_SD_Sum
        
        WholeBrainMean   %
        WBM_R
        WBM_T
        
        WholeBrainSD
        WBSD_R
        WBSD_T
        
        WholeBrainMin
        WholeBrainMax
        WBMin_R
        WBMax_R
        WBMin_T
        WBMax_T
        
        WBCV_mean
        WBCV_R_mean
        WBCV_T_mean
        overlay
        
        %mask roi analysis
        maskFiles
        maskVolumes
        curMaskVol
        maskedVol
    end
    
    methods (Access = private)
        %preprocessing tab functions
        function DICOM_Import(app)
            for g=1:length(app.subdirectories)
                strcat("Current Progress: ", num2str((g/length(app.subdirectories))*100), "%") %display progress in output window
                cDir = strcat(app.subdirectories(g), "\DICOM");
                C = dir(fullfile(cDir,"*.IMA"));
                dicom_import = struct;
                % Data
                fnms={};
                for i = 1:length(C)
                    fnms{i} = convertStringsToChars(strcat(cDir, "\", C(i).name));
                end
                mkdir(strcat(app.subdirectories(g),"\fMRI"));
                %needs to be all dicom files in DICOM folder
                dicom_import.matlabbatch{1}.spm.util.import.dicom.data = fnms';
                dicom_import.matlabbatch{1}.spm.util.import.dicom.root = 'flat';
                dicom_import.matlabbatch{1}.spm.util.import.dicom.outdir = cellstr(strcat(app.subdirectories(g),"\fMRI"));
                dicom_import.matlabbatch{1}.spm.util.import.dicom.protfilter = '.*';
                dicom_import.matlabbatch{1}.spm.util.import.dicom.convopts.format = 'nii';
                dicom_import.matlabbatch{1}.spm.util.import.dicom.convopts.meta = 0;
                dicom_import.matlabbatch{1}.spm.util.import.dicom.convopts.icedims = 0;
                cfg_util('run',dicom_import.matlabbatch);
            end            
        end
        function Realign(app)
            fnms = {};
            
            for g = 1:length(app.subdirectories)
                strcat("Current Progress: ", num2str((g/length(app.subdirectories))*100), "%") %display progress
                cDir = strcat(app.subdirectories(g), "\Adjusted_fMRI");
                C = dir(fullfile(cDir,"*.nii"));
                sub_fnms = {};
                for i=1:length(C)
                    currentName = convertStringsToChars(strcat(cDir, "\", C(i).name, ",1"));
                    sub_fnms{length(sub_fnms)+1} = currentName;
                end
                fnms{g} = sub_fnms';
            end
            %Create realign reslice batch, similar to ui, but in coding. :)
            realign = struct;
            realign.matlabbatch{1}.spm.spatial.realign.estwrite.data = fnms';
            realign.matlabbatch{1}.spm.spatial.realign.estwrite.eoptions.quality = 0.9;
            realign.matlabbatch{1}.spm.spatial.realign.estwrite.eoptions.sep = 4;
            realign.matlabbatch{1}.spm.spatial.realign.estwrite.eoptions.fwhm = 5;
            realign.matlabbatch{1}.spm.spatial.realign.estwrite.eoptions.rtm = 0;
            realign.matlabbatch{1}.spm.spatial.realign.estwrite.eoptions.interp = 2;
            realign.matlabbatch{1}.spm.spatial.realign.estwrite.eoptions.wrap = [0 0 0];
            realign.matlabbatch{1}.spm.spatial.realign.estwrite.eoptions.weight = '';
            realign.matlabbatch{1}.spm.spatial.realign.estwrite.roptions.which = [2 0];
            realign.matlabbatch{1}.spm.spatial.realign.estwrite.roptions.interp = 4;
            realign.matlabbatch{1}.spm.spatial.realign.estwrite.roptions.wrap = [0 0 0];
            realign.matlabbatch{1}.spm.spatial.realign.estwrite.roptions.mask = 1;
            realign.matlabbatch{1}.spm.spatial.realign.estwrite.roptions.prefix = 'r';            
            cfg_util('run',realign.matlabbatch);
            %Since no new image is generated, this is fine.
            for x=1:length(app.subdirectories)
                dDir = strcat(app.subdirectories(x),"\Adjusted_fMRI");
                D = dir(fullfile(dDir,"r*.nii"));
                mkdir(strcat(app.subdirectories(x),"\Realign_fMRI"));
                for j=1:length(D)
                    movefile(strcat(dDir,"\", D(j).name),strcat(app.subdirectories(x),"\Realign_fMRI"));
                end
            end
            
        end
        
        function Timing_Reslice(app)
            for g=1:length(app.subdirectories)
                strcat("Current Progress: ", num2str((g/length(app.subdirectories))*100), "%") %display progress
                fnms = {};
                cDir = strcat(app.subdirectories(g), "\Realign_fMRI");
                C = dir(fullfile(cDir,"r*.nii"));
                info = niftiinfo(strcat(C(1).folder, "\", C(1).name));
                app.numslices = info.raw.dim(4); %get number of slices on case by case basis.                
                sub_fnms = {};                
                for i=1:length(C)
                    sub_fnms{length(sub_fnms)+1} = convertStringsToChars(strcat(cDir, "\", C(i).name, ",1"));
                end
                fnms{1} = sub_fnms';
                timing = struct;
                timing.matlabbatch{1}.spm.temporal.st.scans = fnms';                
                timing.matlabbatch{1}.spm.temporal.st.nslices = app.numslices; %don't make it a user input,autodetect it.
                timing.matlabbatch{1}.spm.temporal.st.tr = app.tr;
                app.ta = app.tr-(app.tr/app.numslices);
                timing.matlabbatch{1}.spm.temporal.st.ta = app.ta;
                timing.matlabbatch{1}.spm.temporal.st.so = 1:app.numslices;
                timing.matlabbatch{1}.spm.temporal.st.refslice = 1;
                timing.matlabbatch{1}.spm.temporal.st.prefix = 'a';                
                cfg_util('run', timing.matlabbatch);
            end
            %case by case basis, or all at once?
            for x=1:length(app.subdirectories)
                dDir = strcat(app.subdirectories(x),"\Realign_fMRI");
                D = dir(fullfile(dDir,"a*.nii"));
                mkdir(strcat(app.subdirectories(x),"\Timing_fMRI"));
                for j=1:length(D)
                    movefile(strcat(dDir,"\", D(j).name),strcat(app.subdirectories(x),"\Timing_fMRI"));
                end
            end
        end
        
        function Spatial_Norm(app)
            for g=1:length(app.subdirectories)
                strcat("Current Progress: ", num2str((g/length(app.subdirectories))*100), "%") %display progress in output window
                cDir = strcat(app.subdirectories(g), "\Timing_fMRI");
                C = dir(fullfile(cDir,"*.nii"));
                spatialnorm = struct;
            
                fnms={};
                for i = 1:length(C)
                    fnms{i} = convertStringsToChars(strcat(cDir, "\", C(i).name, ",1"));
                end
                
                spatialnorm.matlabbatch{1}.spm.spatial.normalise.estwrite.subj.vol = {'D:\Miguel''s Items\PhdStudentWork\DrToosizadeh\fMRI Project\spm12\canonical\single_subj_T1.nii,1'}; %make this choosable               
                spatialnorm.matlabbatch{1}.spm.spatial.normalise.estwrite.subj.resample = fnms'; %make sure to add ',1' to end!                
                spatialnorm.matlabbatch{1}.spm.spatial.normalise.estwrite.eoptions.biasreg = 0.0001;
                spatialnorm.matlabbatch{1}.spm.spatial.normalise.estwrite.eoptions.biasfwhm = 60;
                spatialnorm.matlabbatch{1}.spm.spatial.normalise.estwrite.eoptions.tpm = {'C:\Miguel''s Items\PhdStudentWork\DrToosizadeh\fMRI Project\spm12\tpm\TPM.nii'}; %make this folder choosable
                spatialnorm.matlabbatch{1}.spm.spatial.normalise.estwrite.eoptions.affreg = 'mni';
                spatialnorm.matlabbatch{1}.spm.spatial.normalise.estwrite.eoptions.reg = [0 0.001 0.5 0.05 0.2];
                spatialnorm.matlabbatch{1}.spm.spatial.normalise.estwrite.eoptions.fwhm = 0;
                spatialnorm.matlabbatch{1}.spm.spatial.normalise.estwrite.eoptions.samp = 3;
                spatialnorm.matlabbatch{1}.spm.spatial.normalise.estwrite.woptions.bb = [-78 -112 -70 78 76 85];
                spatialnorm.matlabbatch{1}.spm.spatial.normalise.estwrite.woptions.vox = [app.voxelx app.voxely app.voxelz];
                spatialnorm.matlabbatch{1}.spm.spatial.normalise.estwrite.woptions.interp = 4;
                spatialnorm.matlabbatch{1}.spm.spatial.normalise.estwrite.woptions.prefix = 'w';
            
                cfg_util('run', spatialnorm.matlabbatch);
            end
            for x=1:length(app.subdirectories)
                dDir = strcat(app.subdirectories(x),"\Timing_fMRI");
                D = dir(fullfile(dDir,"w*.nii"));
                for j=1:length(D)
                    movefile(strcat(dDir,"\", D(j).name),strcat(app.subdirectories(x),"\Normal_fMRI"));
                end
            end
        end
        function Smooth(app)
            fnms={};
            for g=1:length(app.subdirectories)
                strcat("Current Progress: ", num2str((g/length(app.subdirectories))*100), "%") %display progress in output window
                cDir = strcat(app.subdirectories(g), "\Timing_fMRI");
                C = dir(fullfile(cDir,"a*.nii"));
                for i = 1:length(C)
                    fnms{length(fnms)+1} = convertStringsToChars(strcat(cDir, "\", C(i).name, ",1"));
                end
            end
            smooth = struct;
            smooth.matlabbatch{1}.spm.spatial.smooth.data = fnms';            
            smooth.matlabbatch{1}.spm.spatial.smooth.fwhm = [app.fwhmx app.fwhmy app.fwhmz];
            smooth.matlabbatch{1}.spm.spatial.smooth.dtype = 0;
            smooth.matlabbatch{1}.spm.spatial.smooth.im = 0;
            smooth.matlabbatch{1}.spm.spatial.smooth.prefix = 's';
            
            cfg_util('run', smooth.matlabbatch);
            
            for x=1:length(app.subdirectories)
                dDir = strcat(app.subdirectories(x),"\Timing_fMRI");
                D = dir(fullfile(dDir,"s*.nii"));
                mkdir(strcat(app.subdirectories(x),"\Smooth_fMRI"));
                for j=1:length(D)
                    movefile(strcat(dDir,"\", D(j).name),strcat(app.subdirectories(x),"\Smooth_fMRI"));
                end
            end
        end
        
        %nifti viewer tab functions

        function displayImages(app)
            
            app.alignImage = app.setModifications();
           
            xLimit = app.Limits(1);
            yLimit = app.Limits(2);
            zLimit = app.Limits(3);
            
            if app.CoronalSlice > yLimit
                app.CoronalSlice = yLimit;
            end
            if app.CoronalSlice <= 0
                app.CoronalSlice = 1;
            end
            if app.SagittalSlice > xLimit
                app.SagittalSlice = xLimit;
            end
            if app.SagittalSlice <= 0
                app.SagittalSlice = 1;
            end
            if app.AxialSlice > zLimit
                app.AxialSlice = zLimit;
            end
            if app.AxialSlice <= 0
                app.AxialSlice = 1;
            end
            
            
            imgA = zeros(yLimit, zLimit); %sagittal slice
            for y=1:yLimit
                for z=1:zLimit
                    imgA(y,z) = app.alignImage(app.SagittalSlice,(yLimit+1)-y,(zLimit+1)-z);
                end
            end
            
            imgB = zeros(xLimit, zLimit); %coronal slice
            for x=1:xLimit
                for z=1:zLimit
                    imgB(x,z) = app.alignImage((xLimit+1)-x,app.CoronalSlice,(zLimit+1)-z);
                end
            end
            
            imgC = zeros(xLimit,yLimit); %axial slice
            for x=1:xLimit
                for y=1:yLimit
                    imgC(x,y) = app.alignImage((xLimit+1)-x,(yLimit+1)-y,app.AxialSlice);
                end
            end
            
            if (app.TabGroup.SelectedTab == app.MaskAdjustmentTab)
                app.montageImg = montage({imgB',imgA',imgC'},'Parent',app.UIAxes2,'Size',[2 2],'DisplayRange',[0 max([max(imgA,[],'all') max(imgB,[],'all') max(imgC,[],'all')])]);
            else
            
                app.montageImg = montage({imgA',imgB',imgC'},'Parent',app.UIAxes2_2,'Size',[1 3],'DisplayRange',[0 max([max(imgA,[],'all') max(imgB,[],'all') max(imgC,[],'all')])]);
            end
        end
        function results = setModifications(app)
            %Center fMRI image onto mask image dimensions.
%             xLimit = app.maskInfo.raw.dim(2);
%             yLimit = app.maskInfo.raw.dim(3);
%             zLimit = app.maskInfo.raw.dim(4);
            %[length(app.curVol(:,1,1)) length(app.curVol(1,:,1)) length(app.curVol(1,1,:))]
            app.Limits = [length(app.curVol(:,1,1)) length(app.curVol(1,:,1)) length(app.curVol(1,1,:))];
%             stdVolInit = zeros(xLimit, yLimit, zLimit);
%             maskCenter = [round(xLimit/2)+1 round(yLimit/2)+1 round(zLimit/2)+1];
%             imgCenter = [round(app.Limits(1)/2)+1 round(app.Limits(2)/2)+1 round(app.Limits(3)/2)+1];
%             
%             for x=-round(xLimit/2):(round(xLimit/2)-1)
%                 for y=-round(yLimit/2):(round(yLimit/2)-1)
%                     for z=-round(zLimit/2):(round(zLimit/2)-1)
%                         Coords = [x y z];
%                         imgCoords = Coords + imgCenter;
%                         maskCoords = Coords + maskCenter;
%                         
%                         if (imgCoords(1)>=1 && imgCoords(1)<=app.Limits(1)) && (imgCoords(2)>=1 && imgCoords(2)<=app.Limits(2)) && (imgCoords(3)>=1 && imgCoords(3)<=app.Limits(3))
%                             stdVolInit(maskCoords(1),maskCoords(2),maskCoords(3)) = app.curVol(imgCoords(1),imgCoords(2),imgCoords(3));
%                         else
%                             stdVolInit(maskCoords(1),maskCoords(2),maskCoords(3)) = 0;
%                         end
%                         
%                     end
%                 end
%             end
            %vol = app.curVol
            stdVolRotatedZ = imrotate3(app.curVol,app.rotationImg(1),[0 0 1],'nearest','crop');%stdVolInit,app.rotationImg(1),[0 0 1],'nearest','crop');
            stdVolRotatedY = imrotate3(stdVolRotatedZ,app.rotationImg(2),[0 1 0],'nearest','crop');
            stdVolRotatedX = imrotate3(stdVolRotatedY,app.rotationImg(3),[1 0 0],'nearest','crop');
            app.Limits = [length(stdVolRotatedX(:,1,1)) length(stdVolRotatedX(1,:,1)) length(stdVolRotatedX(1,1,:))];
            
            results = stdVolRotatedX;
        end
        
        function results = getRotationMatrix(~,yaw,pitch,roll)
            %alpha - yaw, beta - pitch, gamma - roll
            %faster if cos and sin computed prior to building rotation matrix, reduce number of times costly functions like sin and cos are called.
            cosAlpha = cos(yaw);
            cosBeta = cos(pitch);
            cosGamma = cos(roll);
            sinAlpha = sin(yaw);
            sinBeta = sin(pitch);
            sinGamma = sin(roll);
            rotMatTemp = [cosAlpha*cosBeta (cosAlpha*sinBeta*sinGamma - sinAlpha*cosGamma) (cosAlpha*sinBeta*cosGamma+sinAlpha*sinGamma); sinAlpha*cosBeta (sinAlpha*sinBeta*sinGamma+cosAlpha*cosGamma) (sinAlpha*sinBeta*cosGamma-cosAlpha*sinGamma);-sinBeta cosBeta*sinGamma cosBeta*cosGamma];
            results = rotMatTemp;
        end
        
        
        function results = vectorMultiplication(~,Mat1,Mat2) %used for multiplying different sized matrices, keeping smaller matrix only.
            %Mat1 is rotation matrix, and Mat2 is 3d coordinates
            rotVectX = (Mat1(1,1) * Mat2(1)) + (Mat1(1,2) * Mat2(2)) + (Mat1(1,3) * Mat2(3));
            rotVectY = (Mat1(2,1) * Mat2(1)) + (Mat1(2,2) * Mat2(2)) + (Mat1(2,3) * Mat2(3));
            rotVectZ = (Mat1(3,1) * Mat2(1)) + (Mat1(3,2) * Mat2(2)) + (Mat1(3,3) * Mat2(3));
            
            results = [round(rotVectX) round(rotVectY) round(rotVectZ)];
        end
        
        function updateVolumes(app)
            targetFolder = strcat(app.subdirectories(app.curFolder),"\Smooth_fMRI");
            app.files = dir(fullfile(targetFolder,"*.nii"));
            %[1 length(app.files)]
            %app.Slider.Limits = [1 length(app.files)];
            fileInfo = niftiinfo(strcat(app.files(1).folder,"\",app.files(1).name));
            app.vol4D = zeros(fileInfo.raw.dim(2),fileInfo.raw.dim(3),fileInfo.raw.dim(4),length(app.files));
            for t=1:length(app.files)
                app.vol4D(:,:,:,t) = niftiread(strcat(app.files(t).folder,"\",app.files(t).name));
                
                %app.vol4D(isinf(app.vol4D)) = 0;
            end
            
        end
        function selectVolume(app)
            app.curVol = app.vol4D(:,:,:,app.timeN);
            %app.curVol(isnan(app.curVol)) = 0; No longer needed, since I
            %ran a code to remove it permanently.
            app.displayImages();
        end
        function applyChangestoFolder(app)
            targetFolder = strcat(app.subdirectories(app.curFolder),"\Final_fMRI");
            mkdir(targetFolder);
            oldFolder = strcat(app.subdirectories(app.curFolder),"\Realign_fMRI");%"\fMRI");
            
            app.files = dir(fullfile(oldFolder,"*.nii"));
            fileInfo = niftiinfo(strcat(app.files(1).folder,"\",app.files(1).name));
            app.vol4D = zeros(fileInfo.raw.dim(2),fileInfo.raw.dim(3),fileInfo.raw.dim(4),length(app.files));
            
            for t=1:length(app.files)
                app.vol4D(:,:,:,t) = niftiread(strcat(app.files(t).folder,"\",app.files(t).name));
                app.curVol = app.vol4D(:,:,:,t);
                updatedVol = app.setModifications();
                niftiwrite(updatedVol,strcat(targetFolder,"\",extractBefore(app.files(t).name,".nii"),"_mod.nii"));
            end
        end
        %Complexity Analysis Functions
        
        function results = SampleEntropy(~,data, dim, r, tau)
            if nargin < 4, tau = 1; end
            if tau > 1, data = downsample(data, tau); end
            N = length(data);
            correl = zeros(1,2);
            dataMat = zeros(dim+1,N-dim);
            for i = 1:dim+1
                dataMat(i,:) = data(i:N-dim+i-1);
            end
            for m = dim:dim+1
                count = zeros(1,N-dim);
                tempMat = dataMat(1:m,:);
                
                for i = 1:N-m
                    % calculate Chebyshev distance, excluding self-matching case
                    dist = max(abs(tempMat(:,i+1:N-dim) - repmat(tempMat(:,i),1,N-dim-i)));
                    
                    % calculate Heaviside function of the distance
                    % User can change it to any other function
                    % for modified sample entropy (mSampEn) calculation
                    D = (dist < r);
                    
                    count(i) = sum(D)/(N-dim);
                end
                
                correl(m-dim+1) = sum(count)/(N-dim);
            end
            results = log(correl(1)/correl(2));
        end
        
        
        function results = computeMultiscaleEntropy(app,data,scaleMax,stdDataMain) %for fMRI data, use pattern length of 1!
            if nargin<2, scaleMax = 20; end
            scaleMin = 1;
            %stdDataMain = std(data);
            for s=scaleMin:scaleMax %initially tried with m=1; -Inf obtained where brainregions were, so reverted to m=2;
                msEn(s) = app.SampleEntropy(data,2,0.2*stdDataMain,s);%app.SampleEntropy(scaledData,2,0.2*stdDataMain,s); %data, m, r, time delay; r = 0.2 x stdev; try 0.25 x stdev if it doesn't work.
            end
            results = msEn;
        end
        
        function calcSampEnVolume(app)
            app.Intensity = 1:length(app.files);
            volumeDims = size(app.vol4D(:,:,:,1));
            app.VolumetricSampEn = zeros(volumeDims(1), volumeDims(2), volumeDims(3),6);
            %app.VolumetricSampEnN = zeros(volumeDims(1), volumeDims(2), volumeDims(3));
            maxSignal = max(app.vol4D,[],'all','omitnan');
            for z = 1:1:volumeDims(3)
                strcat("Slice ", num2str(z), " of ", num2str(volumeDims(3)))
                for x=1:1:volumeDims(1)
                    for y=1:1:volumeDims(2)
                        app.Intensity(1:length(app.files)) = app.vol4D(x,y,z,1:length(app.files));
%                         for i=1:1:length(app.files)
%                             app.Intensity(i) = app.vol4D(x,y,z,i);
%                         end
                        if max(app.Intensity,[],'all','omitnan')>=0.1*maxSignal
                            int_stats = app.setTimeSeries();
                            
                            %app.VolumetricSampEnN(x,y,z) = app.SampleEntropy(app.N_Intensity, 2, 0.2*int_stats(2),1);%round(sum(tau)/length(tau))); %m needs to be on time-series basis
                            app.VolumetricSampEn(x,y,z,:) = app.computeMultiscaleEntropy(app.N_Intensity,6,int_stats(2)); %app.SampleEntropy(app.Intensity,2,0.2*int_stats(2),1);
                            %app.VolumetricSampEnN(x,y,z,:) = app.computeMultiscaleEntropy(app.N_Intensity,6);
                        else
                            app.VolumetricSampEn(x,y,z,:) = zeros(1,6);
                            
                            %app.VolumetricSampEnN(x,y,z) = 0;
                        end
                    end
                end
            end
            %results = [mean(app.VolumetricSampEn,'all','omitnan') std(app.VolumetricSampEn,1,'all','omitnan'); mean(app.VolumetricSampEnN,'all','omitnan') std(app.VolumetricSampEnN,1,'all')];
        end
        
        function results = setTimeSeries(app)
            app.highpassfilter();            
            mean_Int = mean(app.Intensity);%sum(app.Intensity)/length(app.files);
            std_Int = std(app.Intensity);%sqrt(sum((app.Intensity-mean_Int(1)).^2)/length(app.files));
            app.N_Intensity = (app.Intensity - mean_Int)/std_Int;
            cv_Int = abs(std_Int/mean_Int);                     
            results = [mean_Int std_Int cv_Int];
        end
        
        function highpassfilter(app)      
            
            t=1:1:length(app.Intensity);
            Intensitylow = 1:1:length(app.Intensity);
            W = 250;
            
            for j=1:length(app.Intensity)
                r=exp(-(t-j*1).^2/W);
                Corect = r.*app.Intensity;
                Intensitylow(j) = sum(Corect)/sum(r);
            end
            app.Intensity = app.Intensity-Intensitylow;
        end
        
        function plotTimeSeriesatCurrentVoxel(app)
            app.Intensity = 1:length(app.files);
            for i=1:length(app.files)
                app.Intensity(i) = app.vol4D(app.SagittalSlice,app.CoronalSlice,app.AxialSlice,i);
            end
            app.setTimeSeries();
            hold(app.UIAxes, "off");
            plot(app.UIAxes,(1:1:length(app.files))*app.tr, app.N_Intensity);
            count = 0;
            task = 0; %1 = task, 0 = rest
            app.overlay = 1:1:length(app.files);
            for i=1:1:length(app.files)
                count = count + 1;
                if (count>=15)
                    count = 0;
                    if (task==0)
                        task = 1;
                    else
                        task = 0;
                    end
                end
                app.overlay(i) = task;
            end
            hold(app.UIAxes, "on");
            plot(app.UIAxes, (1:1:length(app.files))*app.tr, app.overlay);
            xlim(app.UIAxes,[1 length(app.files)*app.tr]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mode = 1;
            app.tr = app.TREditField.Value;
            app.voxelx = app.VoxelSizeXEditField.Value;
            app.voxely = app.VoxelSizeYEditField.Value;
            app.voxelz = app.VoxelSizeZEditField.Value;
            app.fwhmx = app.FWHMXEditField.Value;
            app.fwhmy = app.FWHMYEditField.Value;
            app.fwhmz = app.FWHMZEditField.Value;
            app.curFolder = 1;
            app.timeN = 1;
            app.YawKnob.Limits = [0 360];
            app.PitchKnob.Limits = [0 360];
            app.RollKnob.Limits = [0 360];
            app.ready = false;
            app.mouseHold = false;
            app.UIFigure.Name = "fMRI Non-linear Analysis App";
            %app.Lamp.Color = [0 1 0];
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            %app.Lamp.Color = [1 0 0];
            app.path = uigetdir(pwd, "Select Primary Directory");
            app.subdirectories = ["Test" "Test"]; %placeholder for now
            currentsubdirectory = 1; %use to concentate subdirectories array
            if (app.path)
                %subDirA = ["HA" "MCI"]; %first get HA and MCI subdirectories of main path
                A = dir(app.path);
                for i=1:1:length(A)
                    if A(i).name == "HA" || A(i).name == "MCI" %Make sure to grab only HA and MCI directories
                        primeFolder = uitreenode(app.Tree,'Text',A(i).name);
                        cDir = strcat(app.path, "\", A(i).name); %current directory
                        B = dir(cDir);
                        for o=1:1:length(B)
                            if B(o).isdir && o>2
                                cDir2 = strcat(cDir, "\", B(o).name, "\SPMAnalysis");
                                patientFolder = uitreenode(primeFolder,'Text',B(o).name);
                                for p=1:1:4
                                    
                                    
                                    cDir3 = strcat(cDir2, "\Trial", num2str(p));
                                    uitreenode(patientFolder,'Text',strcat("Trial",num2str(p)),'NodeData',currentsubdirectory);
                                    app.subdirectories(currentsubdirectory) = cDir3;
                                    currentsubdirectory = currentsubdirectory + 1;
                                    
                                end
                            end
                        end
                    end
                end
                app.EditField.Value = app.path;
                %mask selection
                app.maskDir = uigetdir(pwd,"Select Primary Mask Directory");%[app.file,app.maskDir] = uigetfile({'*.nii','NIfTI File (*.nii)';},'Select Mask NIfTI File to Overlay');
                if (app.maskDir)
                    A = dir(app.maskDir);
                    app.maskFiles = {};
                    for i=1:1:length(A)
                        if A(i).name == "GeneralRegions" || A(i).name == "NetworkMasks" || A(i).name == "ActivationMasks" %Make sure to grab only HA and MCI directories
                            MaskDir = strcat(app.maskDir,"\",A(i).name);
                            B = dir(fullfile(MaskDir,"*.nii"));
                            for fileIndex=1:length(B)
                                app.maskFiles(end+1) = {strcat(B(fileIndex).folder,"\",B(fileIndex).name)};
                            end
                        end
                    end
                    app.maskInfo = niftiinfo(app.maskFiles{1});
                    app.maskVol = niftiread(app.maskFiles{1});
                    app.maskVol = double(~ceil(app.maskVol));
                    app.Limits = [app.maskInfo.raw.dim(2) app.maskInfo.raw.dim(3) app.maskInfo.raw.dim(4)];
                    app.SagittalSlice = 33;
                    app.CoronalSlice = 33;
                    app.AxialSlice = 33;
                    app.rotationImg = [0 0 0];
                    app.rotMat = app.getRotationMatrix(app.rotationImg(1),app.rotationImg(2),app.rotationImg(3));
                    app.offSet = [0 0 0];
                    app.updateVolumes();
                    app.Slider.Limits = [1 length(app.files)];
                    app.ready = true;
                    %app.Lamp.Color = [0 1 0];
                else
                    msgbox("please select a valid nifti file!");
                    return;
                end
            else
                msgbox("Please select a valid folder containing fMRI data!");
                return;
            end
        end

        % Selection changed function: PreprocessingStepButtonGroup
        function PreprocessingStepButtonGroupSelectionChanged(app, event)
            ButtonOrder = ["DICOM Import" "Realign" "Timing and Reslice" "Spatial Normalization" "Spatial Smoothing"];
            selectedButton = app.PreprocessingStepButtonGroup.SelectedObject;
            app.mode = 1;
            for x=1:1:length(ButtonOrder)
                if selectedButton.Text == ButtonOrder(x)
                    app.mode = x;
                end
            end %Only way I can think of converting radio buttons to mode?
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            switch(app.mode) %keep adding pre-processing steps here
                case 1
                    app.DICOM_Import();
                case 2
                    app.Realign();
                case 3
                    app.Timing_Reslice();
                case 4
                    app.Spatial_Norm();
                case 5
                    app.Smooth();
            end
        end

        % Value changed function: TREditField
        function TREditFieldValueChanged(app, event)
            %value = app.TREditField.Value;
            app.tr = app.TREditField.Value;
        end

        % Value changed function: VoxelSizeXEditField
        function VoxelSizeXEditFieldValueChanged(app, event)
            %value = app.VoxelSizeXEditField.Value;
            app.voxelx = app.VoxelSizeXEditField.Value;
            
        end

        % Value changed function: VoxelSizeYEditField
        function VoxelSizeYEditFieldValueChanged(app, event)
            %value = app.VoxelSizeYEditField.Value;
            app.voxely = app.VoxelSizeYEditField.Value;
            
        end

        % Value changed function: VoxelSizeZEditField
        function VoxelSizeZEditFieldValueChanged(app, event)
            %value = app.VoxelSizeZEditField.Value;
            app.voxelz = app.VoxelSizeZEditField.Value;
            
        end

        % Value changed function: FWHMXEditField
        function FWHMXEditFieldValueChanged(app, event)
            %value = app.FWHMXEditField.Value;
            app.fwhmx = app.FWHMXEditField.Value;
            
        end

        % Value changed function: FWHMYEditField
        function FWHMYEditFieldValueChanged(app, event)
            %value = app.FWHMYEditField.Value;
            app.fwhmy = app.FWHMYEditField.Value;
            
        end

        % Value changed function: FWHMZEditField
        function FWHMZEditFieldValueChanged(app, event)
            %value = app.FWHMZEditField.Value;
            app.fwhmz = app.FWHMZEditField.Value;
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            %app.Lamp2.Color = [1 0 0];
            app.NextButton.Enable = false;
            app.curFolder = app.curFolder + 1;%((app.curFolder + 1 <= length(app.subdirectories))*app.curFolder) + 1;
            if (app.curFolder > length(app.subdirectories))
                app.curFolder = 1;
            end
            app.EditField_2.Value = extractAfter(app.subdirectories(app.curFolder),strcat(app.path,"\"));
            app.updateVolumes();
            app.selectVolume();
            app.Slider.Limits = [1 length(app.files)];
            app.NextButton.Enable = true;
            %app.Lamp2.Color = [0 1 0];
            %app.getVolume();
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            %app.Lamp2.Color = [1 0 0];
            app.BackButton.Enable = false;
            app.curFolder = app.curFolder - 1; %((app.curFolder - 1 > 0)*-(app.curFolder-1)) + length(app.subdirectories);
            if (app.curFolder < 1)
                app.curFolder = length(app.subdirectories);
            end
            app.EditField_2.Value = extractAfter(app.subdirectories(app.curFolder),strcat(app.path,"\"));
            app.updateVolumes();
            app.selectVolume();
            app.Slider.Limits = [1 length(app.files)];
            app.BackButton.Enable = true;
            %app.Lamp2.Color = [0 1 0];
            %app.getVolume();
        end

        % Value changing function: YawKnob
        function YawKnobValueChanging(app, event)
            if (app.TabGroup.SelectedTab == app.MaskAdjustmentTab)
                value = event.Value;
                app.rotationImg = [value app.rotationImg(2) app.rotationImg(3)];%(value*pi)/180 app.rotationImg(2) app.rotationImg(3)];
                app.rotMat = app.getRotationMatrix(app.rotationImg(1),app.rotationImg(2),app.rotationImg(3));
                app.displayImages();
            end
        end

        % Value changing function: PitchKnob
        function PitchKnobValueChanging(app, event)
            if (app.TabGroup.SelectedTab == app.MaskAdjustmentTab)
                value = event.Value;
                app.rotationImg = [app.rotationImg(1) value app.rotationImg(3)];%(value*pi)/180 app.rotationImg(3)];
                app.rotMat = app.getRotationMatrix(app.rotationImg(1),app.rotationImg(2),app.rotationImg(3));
                app.displayImages();
            end
        end

        % Value changing function: RollKnob
        function RollKnobValueChanging(app, event)
            if (app.TabGroup.SelectedTab == app.MaskAdjustmentTab)
                value = event.Value;
                app.rotationImg = [app.rotationImg(1) app.rotationImg(2) value];%(value*pi)/180];
                app.rotMat = app.getRotationMatrix(app.rotationImg(1),app.rotationImg(2),app.rotationImg(3));
                app.displayImages();
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            if (app.TabGroup.SelectedTab == app.MaskAdjustmentTab)
                %app.Lamp2.Color = [1 0 0];
                value = event.Value;
                app.timeN = round(value);
                app.selectVolume();
                %app.Lamp2.Color = [0 1 0];
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if ((selectedTab == app.MaskAdjustmentTab) && (app.ready))
               %app.getStartUpVolume();
                   %app.setAlignImage();
               %app.Lamp2.Color = [1 0 0];
               app.EditField_2.Value = extractAfter(app.subdirectories(app.curFolder),strcat(app.path,"\"));
               app.selectVolume();
               app.Slider.Limits = [1 length(app.files)];
               %app.Lamp2.Color = [0 1 0];
               %app.displayImages();
            end
            if (~app.ready)
                msgbox('Please select a primary folder first.')
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if ~isempty(selectedNodes.NodeData)
                %app.Lamp.Color = [1 0 0];
                app.curFolder = selectedNodes.NodeData;
                app.updateVolumes();
                app.selectVolume();
                app.Slider.Limits = [1 length(app.files)];
                app.EditField_2.Value = extractAfter(app.subdirectories(app.curFolder),strcat(app.path,"\"));
                %app.Lamp.Color = [0 1 0];
            end
        end

        % Button pushed function: ApplytoAllButton
        function ApplytoAllButtonPushed(app, event)
            %apply rotation and mask size to all images, save modified
            %versions to nifti files in new folder.
            %app.Lamp2.Color = [1 0 0];
            for sd=1:length(app.subdirectories)
                app.curFolder = sd;
                app.applyChangestoFolder();
            end
            %app.Lamp2.Color = [0 1 0];
        end

        % Button pushed function: ApplytoThisButton
        function ApplytoThisButtonPushed(app, event)
            app.applyChangestoFolder();
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            imgCData = app.montageImg.CData;
            if app.UIAxes2_2.CurrentPoint(1,1) > 0 && app.UIAxes2_2.CurrentPoint(1,1) <= length(imgCData(1,:)) && app.UIAxes2_2.CurrentPoint(1,2) > 0 && app.UIAxes2_2.CurrentPoint(1,2) <= length(imgCData(:,1))
                app.mouseHold = true;
            end
            
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
           app.mouseHold = false;
           app.plotTimeSeriesatCurrentVoxel();
           %execute displaying time series in selected voxel
           
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.mouseHold                
                imgCData = app.montageImg.CData;
                app.mouseLoc = app.UIAxes2_2.CurrentPoint;
                if app.mouseLoc(1,1) > 0 && app.mouseLoc(1,1) <= length(imgCData(1,:))/3
                    app.mouseState = 1;
                elseif app.mouseLoc(1,1) > length(imgCData(1,:))/3 && app.mouseLoc(1,1) <= 2*length(imgCData(1,:))/3
                    app.mouseState = 2;
                elseif app.mouseLoc(1,1) > 2*length(imgCData(1,:))/3 && app.mouseLoc(1,1) <= length(imgCData(1,:))
                    app.mouseState = 3;
                end
                switch(app.mouseState)
                    case 1 %sagittal
                        imgCenter = [length(imgCData(1,:))/6 length(imgCData(:,1))/2];
                        relLoc = (app.mouseLoc(1,1:2) - imgCenter);
                        app.CoronalSlice = round((app.Limits(1)/2) - relLoc(1)*((app.Limits(1)/2)/imgCenter(1)));
                        app.AxialSlice = round((app.Limits(3)/2) - relLoc(2)*((app.Limits(3)/2)/imgCenter(2)));
                        app.displayImages();
                        return;
                    case 2 %coronal
                        imgCenter = [length(imgCData(1,:))/3+length(imgCData(1,:))/6 length(imgCData(:,1))/2];
                        relLoc = (app.mouseLoc(1,1:2) - imgCenter);
                        app.SagittalSlice = round((app.Limits(2)/2) - relLoc(1)*((app.Limits(2)/2)/(length(imgCData(1,:))/6)));
                        app.AxialSlice = round((app.Limits(3)/2) - relLoc(2)*((app.Limits(3)/2)/imgCenter(2)));
                        app.displayImages();
                        return;
                    case 3 %axial
                        imgCenter = [2*length(imgCData(1,:))/3+length(imgCData(1,:))/6 length(imgCData(:,1))/2];
                        relLoc = (app.mouseLoc(1,1:2) - imgCenter);
                        app.SagittalSlice = round((app.Limits(2)/2) - relLoc(1)*((app.Limits(2)/2)/(length(imgCData(1,:))/6)));
                        app.CoronalSlice = round((app.Limits(1)/2) - relLoc(2)*((app.Limits(1)/2)/imgCenter(2)));
                        app.displayImages();
                        return;
                end
                
            end
        end

        % Button pushed function: SingleTrialSampEnAnalysisButton
        function SingleTrialSampEnAnalysisButtonPushed(app, event)
            if app.ready
                results = app.calcSampEnVolume();
                app.UITable.Data = results;
            end
        end

        % Button pushed function: 
        % SingleParticipantSampEnAnalysisButton
        function SingleParticipantSampEnAnalysisButtonPushed(app, event)
            
        end

        % Button pushed function: 
        % AllParticipantsSampEnAnalysisButton
        function AllParticipantsSampEnAnalysisButtonPushed(app, event)
            if app.ready
                tic
                resultsFolder = strcat(app.path,"\Results");
                mkdir(app.path,"Results")
                excelFileName = strcat(resultsFolder,"\Results.xlsx");
                columnsSet = false;
                columnNames = [{"PatientID"} {"Group"}];
                dataUse = cell(length(app.subdirectories),0);
                %writecell
                %make sure to update a master array with final results for
                %each subdirectory
                %curFolderIndex = 1;
                for curFolderIndex=1:length(app.subdirectories)
                    %compute sampen and msentropy for all trials
                    %tic
                    subdirectory = extractAfter(app.subdirectories(curFolderIndex),strcat(app.path,"\"));
                    childFolderName = extractAfter(subdirectory,"SPMAnalysis\");
                    parentFolderName = extractBetween(subdirectory,"\","\SPMAnalysis");
                    mkdir(resultsFolder,parentFolderName)
                    saveLoc = strcat(resultsFolder,"\",parentFolderName,"\",childFolderName);
                    mkdir(strcat(resultsFolder,"\",parentFolderName),childFolderName)                    
                    strcat("Current Folder: ",parentFolderName,"\",childFolderName)
                    dataUse(curFolderIndex,end+1:end+2) = [{strcat(parentFolderName,"_",childFolderName)} {extractBetween(app.subdirectories(curFolderIndex),strcat(app.path,"\"),strcat("\",parentFolderName))}];
                    app.curFolder = curFolderIndex;                    
                    app.updateVolumes();
                    app.calcSampEnVolume();               
                    mkdir(saveLoc,"SampEnMaps")
                    volUse = zeros(length(app.VolumetricSampEn(:,1,1,1)),length(app.VolumetricSampEn(1,:,1,1)),length(app.VolumetricSampEn(1,1,:,1)));
                    for scale=1:6
                        
                        volUse = app.VolumetricSampEn(:,:,:,scale);
                        niftiwrite(volUse,strcat(saveLoc,"\SampEnMaps\SampEnResult_Scale",num2str(scale),".nii"));
                    end                    
                    %niftiwrite(app.VolumetricSampEnN,strcat(saveLoc,"\SampEnMaps\SampEnResultNorm.nii"));
                    
                    %Apply masks to SampEn maps
                    mkdir(saveLoc,"MaskedMaps");
                    for mask=1:length(app.maskFiles)
                        useMaskVol = double(niftiread(app.maskFiles{mask}));
                        useMaskName = extractAfter(app.maskFiles{mask},strcat(app.maskDir,"\"));
                        volUse = zeros(length(app.VolumetricSampEn(:,1,1,1))-1,length(app.VolumetricSampEn(1,:,1,1))-1,length(app.VolumetricSampEn(1,1,:,1))-1);
                        for scale=1:6
                            volUse = app.VolumetricSampEn(1:length(app.VolumetricSampEn(:,1,1,scale))-1,1:length(app.VolumetricSampEn(1,:,1,scale))-1,1:length(app.VolumetricSampEn(1,1,:,scale))-1,scale) .* useMaskVol;
                            niftiwrite(volUse,strcat(saveLoc,"\MaskedMaps\SampEnResult_Scale",num2str(scale),"_",extractBetween(useMaskName,"\",".nii"),".nii"));
                        end
                        %volUse = app.VolumetricSampEnN(1:length(app.VolumetricSampEnN(:,1,1))-1,1:length(app.VolumetricSampEnN(1,:,1))-1,1:length(app.VolumetricSampEnN(1,1,:))-1) .* useMaskVol;
                        %niftiwrite(volUse,strcat(saveLoc,"\MaskedMaps\SampEnResult_Norm_",extractBetween(useMaskName,"\",".nii"),".nii"));
                    end
                    
                    %write to summary excel sheet for each trial
                    %mkdir(strcat(saveLoc,"\Stats"))
                    wholeFiles = dir(fullfile(strcat(saveLoc,"\SampEnMaps"),"*.nii"));
                    maskedFiles = dir(fullfile(strcat(saveLoc,"\MaskedMaps"),"*.nii"));
                    columns = {};
                    dataMat = {};
                    %dataUse = [];
                    for wholeFile=1:length(wholeFiles)
                        columnName = extractBetween(wholeFiles(wholeFile).name,"SampEnResult_",".nii");
                        columns(end+1) = columnName;
                        wholeFileData = niftiread(strcat(wholeFiles(wholeFile).folder,"\",wholeFiles(wholeFile).name));
                        wholeFileData(wholeFileData==0) = NaN;
                        wholeFileData(isinf(wholeFileData)) = NaN;
                        dataMat(end+1) = num2cell(mean(wholeFileData,"all","double","omitnan"));
                    end
                    for maskedFile=1:length(maskedFiles)
                        columnName = extractBetween(maskedFiles(maskedFile).name,"SampEnResult_",".nii");
                        columns(end+1) = columnName;
                        maskedFileData = niftiread(strcat(maskedFiles(maskedFile).folder,"\",maskedFiles(maskedFile).name));
                        maskedFileData(maskedFileData==0) = NaN;
                        maskedFileData(isinf(maskedFileData)) = NaN;
                        dataMat(end+1) = num2cell(mean(maskedFileData,"all","double","omitnan"));
                    end
                    dataUse(curFolderIndex,end+1:end+length(dataMat)) = dataMat;
                    if ~columnsSet
                        columnNames(end+1:end+length(columns)) = columns;
                        columnsSet = true;
                    end
                    %toc
                end
                
                %Save data to excel sheet.
                writecell(columnNames,excelFileName,'Sheet',1,'Range','A1');
                writecell(dataUse,excelFileName,'Sheet',1,'Range','A2');
                toc
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.NonlinearAnalysisTab = uitab(app.TabGroup);
            app.NonlinearAnalysisTab.Title = 'Non-linear Analysis';
            app.EditField = uieditfield(app.NonlinearAnalysisTab, 'text');
            app.OpenButton = uibutton(app.NonlinearAnalysisTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.Tree = uitree(app.NonlinearAnalysisTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Tooltip = {'Select subdirectory for viewing.'};
            app.UIAxes = uiaxes(app.NonlinearAnalysisTab);
            title(app.UIAxes, 'Voxel Time-Series')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            app.SingleTrialSampEnAnalysisButton = uibutton(app.NonlinearAnalysisTab, 'push');
            app.SingleTrialSampEnAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SingleTrialSampEnAnalysisButtonPushed, true);
            app.SingleTrialSampEnAnalysisButton.Text = {'Single Trial SampEn Analysis'; ''};
            app.UITable = uitable(app.NonlinearAnalysisTab);
            app.UITable.ColumnName = {'Mean'; 'Std Dev'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.SingleParticipantSampEnAnalysisButton = uibutton(app.NonlinearAnalysisTab, 'push');
            app.SingleParticipantSampEnAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SingleParticipantSampEnAnalysisButtonPushed, true);
            app.SingleParticipantSampEnAnalysisButton.Text = 'Single Participant SampEn Analysis';
            app.SingleTimeSeriesDelayCalcButton = uibutton(app.NonlinearAnalysisTab, 'push');
            app.SingleTimeSeriesDelayCalcButton.Enable = 'off';
            app.SingleTimeSeriesDelayCalcButton.Text = {'Single Time-Series Delay Calc'; ''};
            app.AllParticipantsSampEnAnalysisButton = uibutton(app.NonlinearAnalysisTab, 'push');
            app.AllParticipantsSampEnAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AllParticipantsSampEnAnalysisButtonPushed, true);
            app.AllParticipantsSampEnAnalysisButton.Text = 'All Participants SampEn Analysis';
            app.UseROIMasksCheckBox = uicheckbox(app.NonlinearAnalysisTab);
            app.UseROIMasksCheckBox.Text = 'Use ROI Masks';
            app.CombineTrialsCheckBox = uicheckbox(app.NonlinearAnalysisTab);
            app.CombineTrialsCheckBox.Text = 'Combine Trials';
            app.ExistingSampEnMapsCheckBox = uicheckbox(app.NonlinearAnalysisTab);
            app.ExistingSampEnMapsCheckBox.Text = 'Existing SampEn Maps';
            app.UIAxes2_2 = uiaxes(app.NonlinearAnalysisTab);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.MaskAdjustmentTab = uitab(app.TabGroup);
            app.MaskAdjustmentTab.Title = 'Mask Adjustment';
            app.DAxialSagittalandCoronalViewsofCurrentlySelectedfMRIFolderPanel = uipanel(app.MaskAdjustmentTab);
            app.DAxialSagittalandCoronalViewsofCurrentlySelectedfMRIFolderPanel.Title = '4D Axial, Sagittal, and Coronal Views of Currently Selected fMRI Folder';
            app.TimeNPanel = uipanel(app.DAxialSagittalandCoronalViewsofCurrentlySelectedfMRIFolderPanel);
            app.TimeNPanel.Title = 'Time (N)';
            app.Slider = uislider(app.TimeNPanel);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.UIAxes2 = uiaxes(app.DAxialSagittalandCoronalViewsofCurrentlySelectedfMRIFolderPanel);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.AdjustmentPanel = uipanel(app.MaskAdjustmentTab);
            app.AdjustmentPanel.Title = 'Adjustment';
            app.PitchKnobLabel = uilabel(app.AdjustmentPanel);
            app.PitchKnobLabel.Text = 'Pitch';
            app.PitchKnob = uiknob(app.AdjustmentPanel, 'continuous');
            app.PitchKnob.Limits = [0 360];
            app.PitchKnob.ValueChangingFcn = createCallbackFcn(app, @PitchKnobValueChanging, true);
            app.RollKnobLabel = uilabel(app.AdjustmentPanel);
            app.RollKnobLabel.Text = 'Roll';
            app.RollKnob = uiknob(app.AdjustmentPanel, 'continuous');
            app.RollKnob.Limits = [0 360];
            app.RollKnob.ValueChangingFcn = createCallbackFcn(app, @RollKnobValueChanging, true);
            app.YawKnobLabel = uilabel(app.AdjustmentPanel);
            app.YawKnobLabel.Text = 'Yaw';
            app.YawKnob = uiknob(app.AdjustmentPanel, 'continuous');
            app.YawKnob.Limits = [0 360];
            app.YawKnob.ValueChangingFcn = createCallbackFcn(app, @YawKnobValueChanging, true);
            app.Image_4 = uiimage(app.AdjustmentPanel);
            app.XYOffsetLabel = uilabel(app.AdjustmentPanel);
            app.XYOffsetLabel.Text = 'X-Y Offset';
            app.Image_5 = uiimage(app.AdjustmentPanel);
            app.YZOffsetLabel = uilabel(app.AdjustmentPanel);
            app.YZOffsetLabel.Text = 'Y-Z Offset';
            app.Image_6 = uiimage(app.AdjustmentPanel);
            app.XZOffsetLabel = uilabel(app.AdjustmentPanel);
            app.XZOffsetLabel.Text = 'X-Z Offset';
            app.ManagementPanel = uipanel(app.MaskAdjustmentTab);
            app.ManagementPanel.Title = 'Management';
            app.EditField_2 = uieditfield(app.ManagementPanel, 'text');
            app.NextButton = uibutton(app.ManagementPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tooltip = {'Open next folder.'};
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.ManagementPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Tooltip = {'Open last folder.'};
            app.BackButton.Text = 'Back';
            app.ApplytoThisButton = uibutton(app.ManagementPanel, 'push');
            app.ApplytoThisButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoThisButtonPushed, true);
            app.ApplytoThisButton.Tooltip = {'Apply to current folder only.'};
            app.ApplytoThisButton.Text = 'Apply to This';
            app.ApplytoAllButton = uibutton(app.ManagementPanel, 'push');
            app.ApplytoAllButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoAllButtonPushed, true);
            app.ApplytoAllButton.Tooltip = {'Apply to all subdirectories of selected folder.'};
            app.ApplytoAllButton.Text = 'Apply to All';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.PreprocessingStepButtonGroup = uibuttongroup(app.PreprocessingTab);
            app.PreprocessingStepButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PreprocessingStepButtonGroupSelectionChanged, true);
            app.PreprocessingStepButtonGroup.Title = 'Pre-processing Step';
            app.DICOMImportButton = uiradiobutton(app.PreprocessingStepButtonGroup);
            app.DICOMImportButton.Text = 'DICOM Import';
            app.DICOMImportButton.Value = true;
            app.RealignButton = uiradiobutton(app.PreprocessingStepButtonGroup);
            app.RealignButton.Text = 'Realign';
            app.TimingandResliceButton = uiradiobutton(app.PreprocessingStepButtonGroup);
            app.TimingandResliceButton.Text = 'Timing and Reslice';
            app.SpatialNormalizationButton = uiradiobutton(app.PreprocessingStepButtonGroup);
            app.SpatialNormalizationButton.Text = 'Spatial Normalization';
            app.SpatialSmoothingButton = uiradiobutton(app.PreprocessingStepButtonGroup);
            app.SpatialSmoothingButton.Text = 'Spatial Smoothing';
            app.RunButton = uibutton(app.PreprocessingTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TREditFieldLabel = uilabel(app.PreprocessingTab);
            app.TREditFieldLabel.Text = 'TR';
            app.TREditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.TREditField.ValueChangedFcn = createCallbackFcn(app, @TREditFieldValueChanged, true);
            app.TREditField.Value = 2;
            app.VoxelSizeXEditFieldLabel = uilabel(app.PreprocessingTab);
            app.VoxelSizeXEditFieldLabel.Text = 'Voxel Size X';
            app.VoxelSizeXEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.VoxelSizeXEditField.ValueChangedFcn = createCallbackFcn(app, @VoxelSizeXEditFieldValueChanged, true);
            app.VoxelSizeXEditField.Value = 2.5;
            app.VoxelSizeYEditFieldLabel = uilabel(app.PreprocessingTab);
            app.VoxelSizeYEditFieldLabel.Text = 'Voxel Size Y';
            app.VoxelSizeYEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.VoxelSizeYEditField.ValueChangedFcn = createCallbackFcn(app, @VoxelSizeYEditFieldValueChanged, true);
            app.VoxelSizeYEditField.Value = 2.5;
            app.VoxelSizeZEditFieldLabel = uilabel(app.PreprocessingTab);
            app.VoxelSizeZEditFieldLabel.Text = 'Voxel Size Z';
            app.VoxelSizeZEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.VoxelSizeZEditField.ValueChangedFcn = createCallbackFcn(app, @VoxelSizeZEditFieldValueChanged, true);
            app.VoxelSizeZEditField.Value = 3;
            app.FWHMXEditFieldLabel = uilabel(app.PreprocessingTab);
            app.FWHMXEditFieldLabel.Text = 'FWHM X';
            app.FWHMXEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.FWHMXEditField.ValueChangedFcn = createCallbackFcn(app, @FWHMXEditFieldValueChanged, true);
            app.FWHMXEditField.Value = 5;
            app.FWHMYEditFieldLabel = uilabel(app.PreprocessingTab);
            app.FWHMYEditFieldLabel.Text = 'FWHM Y';
            app.FWHMYEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.FWHMYEditField.ValueChangedFcn = createCallbackFcn(app, @FWHMYEditFieldValueChanged, true);
            app.FWHMYEditField.Value = 5;
            app.FWHMZEditFieldLabel = uilabel(app.PreprocessingTab);
            app.FWHMZEditFieldLabel.Text = 'FWHM Z';
            app.FWHMZEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.FWHMZEditField.ValueChangedFcn = createCallbackFcn(app, @FWHMZEditFieldValueChanged, true);
            app.FWHMZEditField.Value = 6;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fMRIComplexityApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end